classdef OpzioniApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OpzioniScreen               matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ParametriTab                matlab.ui.container.Tab
        ConfermaButton              matlab.ui.control.Button
        MinimoBetaEditFieldLabel    matlab.ui.control.Label
        MinimoBetaEditField         matlab.ui.control.NumericEditField
        MassimoBetaEditFieldLabel   matlab.ui.control.Label
        MassimoBetaEditField        matlab.ui.control.NumericEditField
        MinimoSigmaEditFieldLabel   matlab.ui.control.Label
        MinimoSigmaEditField        matlab.ui.control.NumericEditField
        MassimoSigmaEditFieldLabel  matlab.ui.control.Label
        MassimoSigmaEditField       matlab.ui.control.NumericEditField
        MinimoGammaEditFieldLabel   matlab.ui.control.Label
        MinimoGammaEditField        matlab.ui.control.NumericEditField
        MassimoGammaEditFieldLabel  matlab.ui.control.Label
        MassimoGammaEditField       matlab.ui.control.NumericEditField
        DatiInizialiTab             matlab.ui.container.Tab
        R0EditFieldLabel            matlab.ui.control.Label
        R0EditField                 matlab.ui.control.NumericEditField
        PassoEditFieldLabel         matlab.ui.control.Label
        PassoEditField              matlab.ui.control.NumericEditField
        ConfermaButton_2            matlab.ui.control.Button
        TfineEditFieldLabel         matlab.ui.control.Label
        TfineEditField              matlab.ui.control.NumericEditField
        NEditFieldLabel             matlab.ui.control.Label
        NEditField                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    
    methods (Access = private)
        
        function ConfermationFunction(app)
            app.MinimoBetaEditField.FontColor="k";
            app.MassimoBetaEditField.FontColor="k";
            app.MinimoSigmaEditField.FontColor="k";
            app.MassimoSigmaEditField.FontColor="k";
            app.MinimoGammaEditField.FontColor="k";
            app.MassimoGammaEditField.FontColor="k";
            app.PassoEditField.FontColor="k";
            app.TfineEditField.FontColor="k";
            app.R0EditField.FontColor="k";
            app.NEditField.FontColor="k";
            if((app.PassoEditField.Value)>=(app.TfineEditField.Value))
                app.PassoEditField.FontColor="r";
                app.TfineEditField.FontColor="r";
            end
            if((app.PassoEditField.Value)<=0)
                app.PassoEditField.FontColor="r";
            end
            if((app.MinimoBetaEditField.Value)<=0)
                app.MinimoBetaEditField.FontColor="r";
            end
            if((app.MinimoSigmaEditField.Value)<=0)
                app.MinimoSigmaEditField.FontColor="r";
            end
            if((app.MinimoGammaEditField.Value)<=0)
                app.MinimoGammaEditField.FontColor="r";
            end
            if((app.R0EditField.Value)<0)
                app.R0EditField.FontColor="r";
            end
            if((app.R0EditField.Value)>=(app.NEditField.Value))
                app.R0EditField.FontColor="r";
                app.NEditField.FontColor="r";
            end
            if((app.MinimoBetaEditField.Value)>=(app.MassimoBetaEditField.Value))
                app.MinimoBetaEditField.FontColor="r";
                app.MassimoBetaEditField.FontColor="r";
            end
            if((app.MinimoSigmaEditField.Value)>=(app.MassimoSigmaEditField.Value))
                app.MinimoSigmaEditField.FontColor="r";
                app.MassimoSigmaEditField.FontColor="r";
            end
            if((app.MinimoGammaEditField.Value)>=(app.MassimoGammaEditField.Value))
                app.MinimoGammaEditField.FontColor="r";
                app.MassimoGammaEditField.FontColor="r";
            end
            if((app.MinimoBetaEditField.Value)<(app.MassimoBetaEditField.Value) ...
               && (app.MinimoSigmaEditField.Value)<(app.MassimoSigmaEditField.Value)...
               && (app.MinimoGammaEditField.Value)<(app.MassimoGammaEditField.Value)...
               && (app.PassoEditField.Value)<(app.TfineEditField.Value) ...
               && (app.R0EditField.Value)<(app.NEditField.Value) ...
               && (app.PassoEditField.Value)>0 ...
               && (app.MinimoBetaEditField.Value)>0 ...
               && (app.MinimoGammaEditField.Value)>0 ...
               && (app.MinimoSigmaEditField.Value)>0 ...
               && (app.R0EditField.Value)>=0 )
            
                app.CallingApp.BetaMinimum=(app.MinimoBetaEditField.Value);
                app.CallingApp.BetaMaximum=(app.MassimoBetaEditField.Value);
                app.CallingApp.SigmaMinimum=(app.MinimoSigmaEditField.Value);
                app.CallingApp.SigmaMaximum=(app.MassimoSigmaEditField.Value);
                app.CallingApp.GammaMinimum=(app.MinimoGammaEditField.Value);
                app.CallingApp.GammaMaximum=(app.MassimoGammaEditField.Value);
                
                app.CallingApp.passo=(app.PassoEditField.Value);
                app.CallingApp.Tfine=(app.TfineEditField.Value);
                app.CallingApp.R0=(app.R0EditField.Value);
                app.CallingApp.N=(app.NEditField.Value);
                
                app.CallingApp.Beta.Limits = [app.CallingApp.BetaMinimum  app.CallingApp.BetaMaximum];
                app.CallingApp.Sigma.Limits = [app.CallingApp.SigmaMinimum  app.CallingApp.SigmaMaximum];
                app.CallingApp.Gamma.Limits = [app.CallingApp.GammaMinimum  app.CallingApp.GammaMaximum];
                app.CallingApp.S0.Limits(2) = (app.CallingApp.N - app.R0EditField.Value);
                delete(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.CallingApp=MainApp;
            app.MinimoBetaEditField.Value=app.CallingApp.BetaMinimum;
            app.MassimoBetaEditField.Value=app.CallingApp.BetaMaximum;
            app.MinimoSigmaEditField.Value=app.CallingApp.SigmaMinimum;
            app.MassimoSigmaEditField.Value=app.CallingApp.SigmaMaximum;
            app.MinimoGammaEditField.Value=app.CallingApp.GammaMinimum;
            app.MassimoGammaEditField.Value=app.CallingApp.GammaMaximum;
            
            app.PassoEditField.Value=app.CallingApp.passo;
            app.TfineEditField.Value=app.CallingApp.Tfine;
            app.R0EditField.Value=app.CallingApp.R0;
            app.NEditField.Value=app.CallingApp.N;
        end

        % Button pushed function: ConfermaButton
        function ConfermaButtonPushed(app, event)
            ConfermationFunction(app); 
        end

        % Button pushed function: ConfermaButton_2
        function ConfermaButton_2Pushed(app, event)
            ConfermationFunction(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OpzioniScreen = uifigure('Visible', 'off');
            app.OpzioniScreen.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.OpzioniScreen);
            app.ParametriTab = uitab(app.TabGroup);
            app.ParametriTab.Title = 'Parametri';
            app.ConfermaButton = uibutton(app.ParametriTab, 'push');
            app.ConfermaButton.ButtonPushedFcn = createCallbackFcn(app, @ConfermaButtonPushed, true);
            app.ConfermaButton.Text = 'Conferma';
            app.MinimoBetaEditFieldLabel = uilabel(app.ParametriTab);
            app.MinimoBetaEditFieldLabel.Text = 'Minimo Beta';
            app.MinimoBetaEditField = uieditfield(app.ParametriTab, 'numeric');
            app.MassimoBetaEditFieldLabel = uilabel(app.ParametriTab);
            app.MassimoBetaEditFieldLabel.Text = 'Massimo Beta';
            app.MassimoBetaEditField = uieditfield(app.ParametriTab, 'numeric');
            app.MinimoSigmaEditFieldLabel = uilabel(app.ParametriTab);
            app.MinimoSigmaEditFieldLabel.Text = 'Minimo Sigma';
            app.MinimoSigmaEditField = uieditfield(app.ParametriTab, 'numeric');
            app.MassimoSigmaEditFieldLabel = uilabel(app.ParametriTab);
            app.MassimoSigmaEditFieldLabel.Text = 'Massimo Sigma';
            app.MassimoSigmaEditField = uieditfield(app.ParametriTab, 'numeric');
            app.MinimoGammaEditFieldLabel = uilabel(app.ParametriTab);
            app.MinimoGammaEditFieldLabel.Text = 'Minimo Gamma';
            app.MinimoGammaEditField = uieditfield(app.ParametriTab, 'numeric');
            app.MassimoGammaEditFieldLabel = uilabel(app.ParametriTab);
            app.MassimoGammaEditFieldLabel.Text = 'Massimo Gamma';
            app.MassimoGammaEditField = uieditfield(app.ParametriTab, 'numeric');
            app.DatiInizialiTab = uitab(app.TabGroup);
            app.DatiInizialiTab.Title = 'Dati Iniziali';
            app.R0EditFieldLabel = uilabel(app.DatiInizialiTab);
            app.R0EditFieldLabel.Text = 'R0';
            app.R0EditField = uieditfield(app.DatiInizialiTab, 'numeric');
            app.PassoEditFieldLabel = uilabel(app.DatiInizialiTab);
            app.PassoEditFieldLabel.Text = 'Passo';
            app.PassoEditField = uieditfield(app.DatiInizialiTab, 'numeric');
            app.ConfermaButton_2 = uibutton(app.DatiInizialiTab, 'push');
            app.ConfermaButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConfermaButton_2Pushed, true);
            app.ConfermaButton_2.Text = 'Conferma';
            app.TfineEditFieldLabel = uilabel(app.DatiInizialiTab);
            app.TfineEditFieldLabel.Text = 'Tfine';
            app.TfineEditField = uieditfield(app.DatiInizialiTab, 'numeric');
            app.NEditFieldLabel = uilabel(app.DatiInizialiTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.DatiInizialiTab, 'numeric');
            % Show the figure after all components are created
            app.OpzioniScreen.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpzioniApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OpzioniScreen)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OpzioniScreen)
        end
    end
end