classdef SIERModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SIERModelVisualization  matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        ENS0SliderLabel         matlab.ui.control.Label
        E                       matlab.ui.control.Slider
        S0SliderLabel           matlab.ui.control.Label
        S0                      matlab.ui.control.Slider
        CalcolaButton           matlab.ui.control.Button
        R0_ValueTextAreaLabel   matlab.ui.control.Label
        R0_ValueTextArea        matlab.ui.control.TextArea
        GammaSliderLabel        matlab.ui.control.Label
        Gamma                   matlab.ui.control.Slider
        SigmaLabel              matlab.ui.control.Label
        Sigma                   matlab.ui.control.Slider
        BetaLabel               matlab.ui.control.Label
        Beta                    matlab.ui.control.Slider
        OpzioniavanzateButton   matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        Graph                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        BetaMinimum=0.05;      % Description
        BetaMaximum=0.1;  
        SigmaMinimum=0.05;
        SigmaMaximum=0.1;
        GammaMinimum=0.05;
        GammaMaximum=0.1;
        
        N=10000;
        R0=0;
        Tfine=300;
        passo=0.1;
        
        OptionApp;
    end
    
    methods (Access = private)
        
        function OpFunction(app)
            beta = app.Beta.Value;
            sigma = app.Sigma.Value;
            gamma = app.Gamma.Value;
            s0=app.S0.Value;
            E0=(10000-s0)*app.E.Value;
            I0=(10000-s0)*(1-app.E.Value);
            app.R0_ValueTextArea.Value=num2str(beta/gamma);
            [t,y]=FunzioneCalcoloSistema(beta,sigma,gamma,app.N,s0,E0,I0,app.R0,app.Tfine,app.passo);
            plot(app.Graph,t,y);
            legend(app.Graph,{'S','I','E','R'},'Location','Best')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             OpFunction(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SIERModelVisualization.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {446, 446};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: CalcolaButton
        function MonthlyPaymentButtonPushed(app, event)
             OpFunction(app);
            
        end

        % Button pushed function: OpzioniavanzateButton
        function OpzioniavanzateButtonPushed(app, event)
             app.OptionApp=OpzioniApp(app);
        end

        % Close request function: SIERModelVisualization
        function SIERModelVisualizationCloseRequest(app, event)
            delete(app.OptionApp)
            delete(app);          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SIERModelVisualization = uifigure('Visible', 'off');
            app.SIERModelVisualization.Name = 'SIERModel';
            app.SIERModelVisualization.CloseRequestFcn = createCallbackFcn(app, @SIERModelVisualizationCloseRequest, true);
            app.SIERModelVisualization.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SIERModelVisualization);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ENS0SliderLabel = uilabel(app.LeftPanel);
            app.ENS0SliderLabel.Text = 'E%(N-S0)';
            app.E = uislider(app.LeftPanel);
            app.E.Limits = [0 1];
            app.E.Tag = 'E%_Slider';
            app.E.Value = 0.5;
            app.S0SliderLabel = uilabel(app.LeftPanel);
            app.S0SliderLabel.Text = 'S0';
            app.S0 = uislider(app.LeftPanel);
            app.S0.Limits = [0 10000];
            app.S0.Tag = 'S0_Slider';
            app.S0.Value = 7500;
            app.CalcolaButton = uibutton(app.LeftPanel, 'push');
            app.CalcolaButton.ButtonPushedFcn = createCallbackFcn(app, @MonthlyPaymentButtonPushed, true);
            app.CalcolaButton.Text = 'Calcola';
            app.R0_ValueTextAreaLabel = uilabel(app.LeftPanel);
            app.R0_ValueTextAreaLabel.Text = 'R0_Value';
            app.R0_ValueTextArea = uitextarea(app.LeftPanel);
            app.R0_ValueTextArea.Editable = 'off';
            app.GammaSliderLabel = uilabel(app.LeftPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.Gamma = uislider(app.LeftPanel);
            app.Gamma.Limits = [0.05 0.1];
            app.Gamma.Tag = 'S0_Slider';
            app.Gamma.Value = 0.05;
            app.SigmaLabel = uilabel(app.LeftPanel);
            app.SigmaLabel.Text = 'Sigma';
            app.Sigma = uislider(app.LeftPanel);
            app.Sigma.Limits = [0.05 0.1];
            app.Sigma.Tag = 'S0_Slider';
            app.Sigma.Value = 0.05;
            app.BetaLabel = uilabel(app.LeftPanel);
            app.BetaLabel.Text = 'Beta';
            app.Beta = uislider(app.LeftPanel);
            app.Beta.Limits = [0.05 0.1];
            app.Beta.Tag = 'S0_Slider';
            app.Beta.Value = 0.08;
            app.OpzioniavanzateButton = uibutton(app.LeftPanel, 'push');
            app.OpzioniavanzateButton.ButtonPushedFcn = createCallbackFcn(app, @OpzioniavanzateButtonPushed, true);
            app.OpzioniavanzateButton.Text = 'Opzioni avanzate';
            app.RightPanel = uipanel(app.GridLayout);
            app.Graph = uiaxes(app.RightPanel);
            title(app.Graph, 'Andamento Variabili')
            xlabel(app.Graph, 'Tempo')
            ylabel(app.Graph, 'Persone')
            app.Graph.YTick = [0 2000 4000 6000 8000 10000];
            app.Graph.YTickLabel = {'0'; '2000'; '4000'; '6000'; '8000'; '10000'};
            % Show the figure after all components are created
            app.SIERModelVisualization.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIERModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SIERModelVisualization)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SIERModelVisualization)
        end
    end
end